classdef network_map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TrafficMonitorButton         matlab.ui.control.Button
        TrafficSwitch                matlab.ui.control.Switch
        TrafficSwitchLabel           matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        StopSimButton                matlab.ui.control.Button
        SwitchNodeNameLabel          matlab.ui.control.Label
        SwitchNodeName               matlab.ui.control.Switch
        SwitchConnectionCircleLabel  matlab.ui.control.Label
        SwitchConnectionCircle       matlab.ui.control.Switch
        Lamp                         matlab.ui.control.Lamp
        MovementSwitch               matlab.ui.control.Switch
        MovementSwitchLabel          matlab.ui.control.Label
        NodeConfigurationPanel       matlab.ui.container.Panel
        ModifyNodeButton             matlab.ui.control.Button
        VelocityyEditField           matlab.ui.control.NumericEditField
        VelocityyEditFieldLabel      matlab.ui.control.Label
        VelocityxEditField           matlab.ui.control.NumericEditField
        VelocityxEditFieldLabel      matlab.ui.control.Label
        PositionyEditField           matlab.ui.control.NumericEditField
        PositionyEditFieldLabel      matlab.ui.control.Label
        PositionxEditField           matlab.ui.control.NumericEditField
        PositionxEditFieldLabel      matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        NodeDropDownLabel            matlab.ui.control.Label
        SimulationTimeField          matlab.ui.control.TextArea
        SimulationTimeLabel          matlab.ui.control.Label
        LinkMonitorButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    %%
    %
    %   LOG_TX_PACKET
    %   tx_time | source_add | pkt_id | dest_add
    %
    %   LOG_RX_PACKET
    %   rx_time | source_add | pkt_id | dest_add | rx_node 
    %
    %   Calcular: 
    %       - Número de pacotes perdidos
    %       - Número de pacotes duplicados
    %%


    %%
    %
    %   TO-DO:
    %       - Criar envio e receçao de pacotes (criar tabela)
    %       - Utilizar ficheiro separado para geriar pacotes e ler pacotes
    %       - Janela de configuração de Nó nesta app
    %%
    properties (Access = public)
        CallingApp % Description
        NetMon
        PktTable
        %NETWORK.node_list
        radius = 1.0;
        timer_sim_t;
        simulationTime;
        timer_mov_t;
        showConnection = 0;
        showName = 1;
        SIM_DATA;
        timer_lock = 0;
        timer_sim_prev_status = 0;
        timer_mov_prev_status = 0;
        timer_traf_prev_status = 0;
        timer_traf_t;
        NETWORK;
        LOG_PKT = struct('tx_packet', [], 'rx_packet', []);
    end
    
    %% ALL TIMERS
    methods (Access = private)
  
        function StopAllTimers(app)
            
            % SIMULATION TIMER
            R = get(app.timer_sim_t, 'Running');

            if isequal(R, 'on')
                app.timer_sim_prev_status = 1;
            else
                app.timer_sim_prev_status = 0;
            end

            app.timerSimStop();
            
            % MOVEMENT TIMER
            R = get(app.timer_mov_t, 'Running');

            if isequal(R, 'on')
                app.timer_mov_prev_status = 1;
            else
                app.timer_mov_prev_status = 0;
            end

            app.timerMoveStop();
    
            % TRAFIC TIMER
            R = get(app.timer_traf_t, 'Running');

            if isequal(R, 'on')
                app.timer_traf_prev_status = 1;
            else
                app.timer_traf_prev_status = 0;
            end

            app.timerTrafficStop();

            app.timer_lock = 1;
        end

        function ResumeAllTimers(app)
            
            app.timer_lock = 0;

            if app.timer_sim_prev_status == 1
                app.timerSimStart();
            end

            if app.timer_mov_prev_status == 1
                app.timerMoveStart();
            end

            if app.timer_traf_prev_status == 1
                app.timerTrafficStart();
            end
        end

    end

    %% Simulation Timing
    methods (Access = private)

        function timerSimInit(app)
            app.timer_sim_t = timer;
            
            app.timer_sim_t.TimerFcn = @app.updateSimTime;
            app.timer_sim_t.Period = 0.1;
            app.timer_sim_t.StartDelay = 0;
            app.timer_sim_t.ExecutionMode = 'fixedSpacing';
            
            app.simulationTime = 0;
            sec = seconds(app.simulationTime);
            sec.Format = 'hh:mm:ss';
            app.SimulationTimeField.Value = string(sec);
        end

        function timerSimStart(app)
            if(app.timer_lock)
                app.timer_sim_prev_status = 1;
            else
                start(app.timer_sim_t);
            end
        end
        
        function timerSimStop(app)
            if(app.timer_lock)
                app.timer_sim_prev_status = 0;
            else
                stop(app.timer_sim_t);
            end
        end

        function updateSimTime(app,~,~)
            app.simulationTime = app.simulationTime + app.timer_sim_t.Period;
            sec = seconds(app.simulationTime);
            sec.Format = 'hh:mm:ss';
            app.SimulationTimeField.Value = string(sec);

            app.gatherData();
        end
    end
    
    %% Gather Simulation Data
    methods(Access = private)

        function dist = computeDistance(app, source_node, dest_node, node_list)
                        
            x_src = node_list(source_node).x;
            y_src = node_list(source_node).y;
        
            x_dest = node_list(dest_node).x;
            y_dest = node_list(dest_node).y;

            dist = sqrt((x_src - x_dest)^2+(y_src - y_dest)^2);

        end

        function freq = computeDoppler(app, source_node, dest_node, node_list, distance)
            
            len = length(distance(:,1));
            original_freq = node_list(source_node).frequency;
            
            if len == 1
                freq = node_list(source_node).frequency;
            else
                velocity = (distance(len, dest_node) - distance(len-1, dest_node));

                freq = original_freq*(299792.458 + velocity)/(299792.458 + 0);
            end
        end
        
        function gatherData(app)

            for i = 1:length(app.NETWORK.node_list)

                dist = zeros(1, length(app.NETWORK.node_list));
                ll = zeros(1, length(app.NETWORK.node_list));
                lb = zeros(1, length(app.NETWORK.node_list));
                prop_delay = zeros(1, length(app.NETWORK.node_list));
                doppler = zeros(1, length(app.NETWORK.node_list));

                frequency = app.NETWORK.node_list(i).frequency;
                tx_power = app.NETWORK.node_list(i).tx_power;
                
                for k = 1:length(app.NETWORK.node_list)
                        dist(k) = app.computeDistance(i, k, app.NETWORK.node_list);
                        prop_delay(k) = dist(k) / 299792.458;
                    if (dist(k) ~= 0)
                        ll(k) = friis(frequency, dist(k));
                        lb(k) = tx_power - ll(k);
                    end
                end

                app.SIM_DATA(i).distance = [app.SIM_DATA(i).distance; dist];
                app.SIM_DATA(i).link_budget = [app.SIM_DATA(i).link_budget; lb];
                app.SIM_DATA(i).prop_delay = [app.SIM_DATA(i).prop_delay; prop_delay];

                for k= 1:length(app.NETWORK.node_list)
                        doppler(k) = app.computeDoppler(i, k, app.NETWORK.node_list, app.SIM_DATA(i).distance);
                end

                app.SIM_DATA(i).doppler = [app.SIM_DATA(i).doppler; doppler];
                app.SIM_DATA(i).counter = app.SIM_DATA(i).counter + 1;
                app.SIM_DATA(i).time = [app.SIM_DATA(i).time  app.simulationTime];

                if app.SIM_DATA(i).counter > (200/app.timer_sim_t.Period)
                    app.SIM_DATA(i).counter = (200/app.timer_sim_t.Period);
                    app.SIM_DATA(i).distance = app.SIM_DATA(i).distance(2:end,:);
                    app.SIM_DATA(i).link_budget = app.SIM_DATA(i).link_budget(2:end,:);
                    app.SIM_DATA(i).prop_delay = app.SIM_DATA(i).prop_delay(2:end,:);
                    app.SIM_DATA(i).doppler = app.SIM_DATA(i).doppler(2:end,:);
                    app.SIM_DATA(i).time = app.SIM_DATA(i).time(2:end);
                end
            end

        end
    end
    
    %% Movement task
    methods (Access = private)
        
        function timerMovTime(app)
            app.timer_mov_t = timer;
            
            app.timer_mov_t.TimerFcn = @app.updateMovementPositions;
            app.timer_mov_t.Period = 0.1;
            app.timer_mov_t.StartDelay = 0;
            app.timer_mov_t.ExecutionMode = 'fixedSpacing';
        end

        function timerMoveStart(app)
            if(app.timer_lock)
                app.timer_mov_prev_status = 1;
            else
                start(app.timer_mov_t);
            end
        end

        function timerMoveStop(app)
            if(app.timer_lock)
                app.timer_mov_prev_status = 0;
            else
                stop(app.timer_mov_t);
            end
        end

        function updateMovementPositions(app,~,~)
            
            for i = 1:length(app.NETWORK.node_list)
                % Compute new X position based on current vx
                tmp_vx = app.NETWORK.node_list(i).vx;
                tmp_x = app.NETWORK.node_list(i).x + tmp_vx * app.timer_mov_t.Period;
                % Compute new Y position based on current vy
                tmp_vy =  app.NETWORK.node_list(i).vy;
                tmp_y = app.NETWORK.node_list(i).y + tmp_vy * app.timer_mov_t.Period;
                
                % If position X is out of simulation area, then invert vx
                if (tmp_x > app.CallingApp.area_x) || (tmp_x < 0) 
                    tmp_vx = - tmp_vx;
                    tmp_x = app.NETWORK.node_list(i).x + tmp_vx * app.timer_mov_t.Period;
                end
                
                if (tmp_y > app.CallingApp.area_y) || (tmp_y < 0) % If position Y is out of simulation area, then invert vy
                    tmp_vy = - tmp_vy;
                    tmp_y = app.NETWORK.node_list(i).y + tmp_vy * app.timer_mov_t.Period;
                end

                % Update new (X,Y) position
                app.NETWORK.node_list(i).x = tmp_x;
                app.NETWORK.node_list(i).y = tmp_y;
                
                % Update new (vx, vy) velocities
                app.NETWORK.node_list(i).vx = tmp_vx;
                app.NETWORK.node_list(i).vy = tmp_vy;
            end
            
            app.updateNetMap();
            app.updateNodeTable();
        end
        
    end
    
    %% Update position and velocity vectors
    methods (Access = private)
        
        function initNodeDropDown(app)
            str_id = [];

            for i = 1:length(app.NETWORK.node_list)
                str_id = [str_id string(app.NETWORK.node_list(i).id)];
            end

            app.NodeDropDown.Items = str_id;
            app.NodeDropDown.Value = app.NodeDropDown.Items(1);
        end

        function enableNodeTable(app, enable)
            if(enable)
                app.ModifyNodeButton.Enable = "on";
                app.VelocityxEditField.Editable = "on";
                app.VelocityyEditField.Editable = "on";
                app.PositionxEditField.Editable = "on";
                app.PositionyEditField.Editable = "on";
            else
                app.ModifyNodeButton.Enable = "off";
                app.VelocityxEditField.Editable = "off";
                app.VelocityyEditField.Editable = "off";
                app.PositionxEditField.Editable = "off";
                app.PositionyEditField.Editable = "off";
            end
        end

        function updateNodeTable(app)
            node_id = str2double(app.NodeDropDown.Value);

            app.PositionxEditField.Value = app.NETWORK.node_list(node_id).x;
            app.PositionyEditField.Value = app.NETWORK.node_list(node_id).y;
            app.VelocityxEditField.Value = app.NETWORK.node_list(node_id).vx;
            app.VelocityyEditField.Value = app.NETWORK.node_list(node_id).vy;
        end

        function updateNodePosVel(app)
            node_id = str2double(app.NodeDropDown.Value);

            app.NETWORK.node_list(node_id).x = app.PositionxEditField.Value;
            app.NETWORK.node_list(node_id).y = app.PositionyEditField.Value;
            app.NETWORK.node_list(node_id).vx = app.VelocityxEditField.Value;
            app.NETWORK.node_list(node_id).vy = app.VelocityyEditField.Value;

            app.updateNetMap();
        end
    end

    %% Traffic task
    methods (Access = private)
        
        function timerTrafficInit(app)
            app.timer_traf_t = timer;
            
            app.timer_traf_t.TimerFcn = @app.manageTraffic;
            app.timer_traf_t.Period = 0.2;
            app.timer_traf_t.StartDelay = 0;
            app.timer_traf_t.ExecutionMode = 'fixedSpacing';
        end

        function timerTrafficStart(app)
            if(app.timer_lock)
                app.timer_traf_prev_status = 1;
            else
                start(app.timer_traf_t);
            end
        end

        function timerTrafficStop(app)
            if(app.timer_lock)
                app.timer_traf_prev_status = 0;
            else
                stop(app.timer_traf_t);
            end
        end
        
        function manageTraffic(app,~,~)
            app.NETWORK = app.NETWORK.generateTxPackets(app.simulationTime, randi([1 100], 1, 1));
            app.NETWORK = app.NETWORK.getRxPackets(app.simulationTime);
        end

    end
    
  
    %% Close Window Function
    methods (Access = public)
        function closeApp(app)
            try
                app.PktTable.closeAppWindow();
            catch
                delete(app.PktTable);
            end
            delete(app.NetMon)
            delete(app)      
        end
    end
    
    %% Network Mapping Graphic
    methods (Access = private)
        %% Set Connection Circle Visibility
        function setConnectionVisibility(app)
            if strcmp(app.SwitchConnectionCircle.Value, 'On')
                app.showConnection = 1;
                app.addConnectionCircle();
                
            else
                app.showConnection = 0;
                app.deleteConnectionCircle();
            end
        end

        function deleteConnectionCircle(app)
            for i = 1:length(app.NETWORK.node_list)
                delete(app.NETWORK.node_list(i).circle_connection);
            end
        end
        
        function addConnectionCircle(app)
            if app.showConnection == 1
                for i = 1:length(app.NETWORK.node_list)
                    app.NETWORK.node_list(i).circle_connection = app.drawCircle(app.NETWORK.node_list(i), app.NETWORK.node_list(i).range, 1);
                end
            end
        end

        %% Set Node Name Visibility
        function setNodeNameVisibility(app)
            if strcmp(app.SwitchNodeName.Value, 'On')
                app.showName = 1;
                app.addNameText();
            else
                app.showName = 0;
                app.deleteNameText();
            end
        end

        function deleteNameText(app)
            for i = 1:length(app.NETWORK.node_list)
                delete(app.NETWORK.node_list(i).text);
            end
        end

        function addNameText(app)
            if app.showName == 1
                for i = 1:length(app.NETWORK.node_list)
                    app.NETWORK.node_list(i).text = app.addName(app.NETWORK.node_list(i));
                end
            end 
        end
        
        %% Update Net MAP
        function updateNetMap(app)

            for i=1:length(app.NETWORK.node_list)

                delete(app.NETWORK.node_list(i).circle_node);
                delete(app.NETWORK.node_list(i).circle_connection);
                delete(app.NETWORK.node_list(i).text);
                
                app.NETWORK.node_list(i).circle_node = app.drawCircle(app.NETWORK.node_list(i), app.radius, 0);

                app.NETWORK.node_list(i).range = app.connectionRadius(app.NETWORK.node_list(i));
                
                if app.showConnection == 1
                    app.NETWORK.node_list(i).circle_connection = app.drawCircle(app.NETWORK.node_list(i), app.NETWORK.node_list(i).range, 1);
                end

                if app.showName == 1
                    app.NETWORK.node_list(i).text = app.addName(app.NETWORK.node_list(i));
                end             
            end
        end
        
        %% Initialize Net Map
        function initNetMap(app)

            circles = findobj('type', 'rectangle');
            delete(circles);
            
            for i=1:length(app.NETWORK.node_list)
                app.NETWORK.node_list(i).circle_node = app.drawCircle(app.NETWORK.node_list(i), app.radius, 0);

                app.NETWORK.node_list(i).range = app.connectionRadius(app.NETWORK.node_list(i));
                
                if app.showConnection == 1
                    app.NETWORK.node_list(i).circle_connection = app.drawCircle(app.NETWORK.node_list(i), app.NETWORK.node_list(i).range, 1);
                end

                if app.showName == 1
                    app.NETWORK.node_list(i).text = app.addName(app.NETWORK.node_list(i));
                end
            end
            
        end
        
        function rect = drawCircle(app, node, radius, lineStyle)
            
            pos = [node.x-radius, node.y-radius, radius*2, radius*2];
    
            rect = rectangle( 'Parent', app.UIAxes, ...
                              'Position',pos,...
                              'curvature',[1,1],...
                              'lineWidth',1 );

            if lineStyle == 1
                rect.EdgeColor = [0.8500 0.3250 0.0980];
                rect.LineWidth = 0.5;
            else
                rect.EdgeColor = [0 0.4470 0.7410];
                rect.FaceColor = [0 0.4470 0.7410];
                rect.LineStyle = "-";
            end
        end

        function rect_name = addName(app, node)
            
            get(app.UIAxes, 'position');

            rect_name = text(app.UIAxes, node.x, node.y, string(node.id),...
                              'HorizontalAlignment', 'center', ...
                              'VerticalAlignment','top');

        end

        function range = connectionRadius(app, node)
            range = inverted_friis(node.frequency, -(node.sensitivity-node.tx_power));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NODE)
            %%Store the main app object
            app.CallingApp = mainapp;
            %app.NETWORK.node_list = NODE;
            app.NETWORK = network(NODE);
            
            %% Create UI Axes to display Network Nodes
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'center')

            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = 'white';
            app.UIAxes.YColor = 'white';
            disableDefaultInteractivity(app.UIAxes);
            
            hold(app.UIAxes, 'on');
            
            %% Get Network Map Dispaly Values
            if strcmp(app.SwitchConnectionCircle.Value, 'On')
                app.showConnection = 1;
            else
                app.showConnection = 0;
            end
            
            if strcmp(app.SwitchNodeName.Value, 'On')
                app.showName = 1;
            else
                app.showName = 0;
            end
            
            %% Initialize SIM_DATA
            sim_data = [];
            for i = 1:length(app.NETWORK.node_list)
                sim_data = [sim_data SIM_DATA(i)];
            end
            app.SIM_DATA = sim_data;

            %% Display Network NNodes
            app.initNetMap();

            %% Node table initialization
            app.initNodeDropDown();
            app.updateNodeTable();
            
            %% Start Simulation Timer
            app.timerSimInit();
            app.timerSimStart();
            
            %% Start Movement Timer
            app.timerMovTime();

            %% Start Traffic Timer
            app.timerTrafficInit();
            
            
            pause(0.5);

            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: LinkMonitorButton
        function LinkMonitorButtonPushed(app, event)
            if isempty(app.NetMon)
                app.NetMon = net_monitor(app);
            else
                delete(app.NetMon);
                app.NetMon = net_monitor(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.PktTable.closeAppWindow(); 
            catch
                delete(app.PktTable);
            end

            delete(app.NetMon)
            delete(app.CallingApp)
            delete(app)            
        end

        % Value changed function: MovementSwitch
        function MovementSwitchValueChanged(app, event)
            value = app.MovementSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = 'green';
                app.timerMoveStart();
                app.enableNodeTable(0);
            else
                app.Lamp.Color = 'red';
                app.timerMoveStop();
                app.enableNodeTable(1);
            end
        end

        % Value changed function: SwitchConnectionCircle
        function SwitchConnectionCircleValueChanged(app, event)
            app.setConnectionVisibility();
        end

        % Value changed function: SwitchNodeName
        function SwitchNodeNameValueChanged(app, event)
            app.setNodeNameVisibility();
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            if strcmp(app.StopSimButton.Text, "Stop Sim")
                app.StopSimButton.Text = "Resume Sim";
                app.StopAllTimers();
                assignin('base','SIM_DATA', app.SIM_DATA);
                assignin('base','NETWORK', app.NETWORK);
                assignin('base','LOG_PKT', app.LOG_PKT);
            else
                app.StopSimButton.Text = "Stop Sim";
                app.ResumeAllTimers();
            end
        end

        % Value changed function: TrafficSwitch
        function TrafficSwitchValueChanged(app, event)
            value = app.TrafficSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_2.Color = 'green';
                app.timerTrafficStart();
            else
                app.Lamp_2.Color = 'red';
                app.timerTrafficStop();
            end
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.updateNodeTable();
        end

        % Button pushed function: ModifyNodeButton
        function ModifyNodeButtonPushed(app, event)
            app.updateNodePosVel();
        end

        % Button pushed function: TrafficMonitorButton
        function TrafficMonitorButtonPushed(app, event)
            app.PktTable = network_pkt_table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 105];
            app.UIAxes.YLim = [0 105];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.LinkMonitorButton = uibutton(app.UIFigure, 'push');
            app.LinkMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @LinkMonitorButtonPushed, true);
            app.LinkMonitorButton.Text = 'Link Monitor';
            app.SimulationTimeLabel = uilabel(app.UIFigure);
            app.SimulationTimeLabel.Text = 'Simulation Time';
            app.SimulationTimeField = uitextarea(app.UIFigure);
            app.NodeConfigurationPanel = uipanel(app.UIFigure);
            app.NodeConfigurationPanel.Title = 'Node Configuration';
            app.NodeDropDownLabel = uilabel(app.NodeConfigurationPanel);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.NodeConfigurationPanel);
            app.NodeDropDown.Editable = 'on';
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.PositionxEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.PositionxEditFieldLabel.Text = 'Position: x';
            app.PositionxEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.PositionxEditField.Limits = [0 100];
            app.PositionxEditField.ValueDisplayFormat = '%.2f';
            app.PositionyEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.PositionyEditFieldLabel.Text = 'Position: y';
            app.PositionyEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.PositionyEditField.Limits = [0 100];
            app.PositionyEditField.ValueDisplayFormat = '%.2f';
            app.VelocityxEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.VelocityxEditFieldLabel.Text = 'Velocity: x';
            app.VelocityxEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.VelocityxEditField.Limits = [-10 10];
            app.VelocityxEditField.ValueDisplayFormat = '%.2f';
            app.VelocityyEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.VelocityyEditFieldLabel.Text = 'Velocity: y';
            app.VelocityyEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.VelocityyEditField.Limits = [-10 10];
            app.VelocityyEditField.ValueDisplayFormat = '%.2f';
            app.ModifyNodeButton = uibutton(app.NodeConfigurationPanel, 'push');
            app.ModifyNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyNodeButtonPushed, true);
            app.ModifyNodeButton.Text = 'Modify';
            app.MovementSwitchLabel = uilabel(app.UIFigure);
            app.MovementSwitchLabel.Text = 'Movement';
            app.MovementSwitch = uiswitch(app.UIFigure, 'slider');
            app.MovementSwitch.ValueChangedFcn = createCallbackFcn(app, @MovementSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchConnectionCircle = uiswitch(app.UIFigure, 'slider');
            app.SwitchConnectionCircle.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectionCircleValueChanged, true);
            app.SwitchConnectionCircleLabel = uilabel(app.UIFigure);
            app.SwitchConnectionCircleLabel.Text = 'Show Connection Circle';
            app.SwitchNodeName = uiswitch(app.UIFigure, 'slider');
            app.SwitchNodeName.ValueChangedFcn = createCallbackFcn(app, @SwitchNodeNameValueChanged, true);
            app.SwitchNodeName.Value = 'On';
            app.SwitchNodeNameLabel = uilabel(app.UIFigure);
            app.SwitchNodeNameLabel.Text = 'Show Node Name';
            app.StopSimButton = uibutton(app.UIFigure, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.TrafficSwitchLabel = uilabel(app.UIFigure);
            app.TrafficSwitchLabel.Text = 'Traffic';
            app.TrafficSwitch = uiswitch(app.UIFigure, 'slider');
            app.TrafficSwitch.ValueChangedFcn = createCallbackFcn(app, @TrafficSwitchValueChanged, true);
            app.TrafficMonitorButton = uibutton(app.UIFigure, 'push');
            app.TrafficMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @TrafficMonitorButtonPushed, true);
            app.TrafficMonitorButton.Text = 'Traffic Monitor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = network_map(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end