classdef pkt_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NodeTrafficLabel  matlab.ui.control.Label
        RXPacketsPanel    matlab.ui.container.Panel
        RxTable           matlab.ui.control.Table
        TXPacketsPanel    matlab.ui.container.Panel
        TxTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp;
        selectedNode;
        timer_monitor;
    end
    
    %%
    %
    %   TO-DO:
    %       - Create table with all events (tx and rx packets) per node
    %%

    %% TIMER
    methods (Access = private)
        function timerInit(app)
            app.timer_monitor = timer;
            
            app.timer_monitor.TimerFcn = @app.updateTables;
            app.timer_monitor.Period = 1;
            app.timer_monitor.StartDelay = 0;
            app.timer_monitor.ExecutionMode = 'fixedSpacing';
            
        end

        function timerStart(app)
            start(app.timer_monitor)
        end
        
        function timerStop(app)
            stop(app.timer_monitor);
        end

        function updateTables(app,~,~)
            app.updateTxTable();
            app.updateRxTable();
        end
    end

    %% UTILITIES
    methods (Access = private)

        % Get the number of existing nodes from the main app
        function n_nodes = getNumberOfNodes(app)
            n_nodes = length(app.mainApp.NETWORK.node_list);
        end

        % Get the node id list from the main app
        function node_id = getNodeIdList(app)

            n_nodes = app.getNumberOfNodes();

            node_id = zeros(1, n_nodes);

            for k = 1:n_nodes
                node_id(k) = app.mainApp.NETWORK.node_list(k).id;
            end
        end

        % Reposition App
        function repositionApp(app)
            
            movegui(app.UIFigure, "northwest");

            current = get(app.UIFigure, 'Position');

            curr_x = current(1); 
            curr_y = current(2);
            width = current(3);
            heigth = current(4);

            new_x = curr_x + 446;

            app.UIFigure.Position = [new_x curr_y width heigth];
        end
    end

    %% TX TABLE
    methods (Access = public)

        function updateTxTable(app)

            node = app.selectedNode;
            
            tx_packet_list = app.mainApp.NETWORK.node_list(node).tx_packet;
            
            app.TxTable.Data = [];

            if ~isempty(tx_packet_list)

                time = zeros(length(tx_packet_list), 1);
                src = zeros(length(tx_packet_list), 1);
                dest = zeros(length(tx_packet_list), 1);
                pkt_id = zeros(length(tx_packet_list), 1);
                pkt_type = zeros(length(tx_packet_list), 1);

                for k = 1:length(tx_packet_list)
                    time(k) = tx_packet_list(k).timestamp;
                    src(k) = tx_packet_list(k).header.src;
                    dest(k) = tx_packet_list(k).header.dest;

                    pkt_id_tmp = tx_packet_list(k).header.pkt_id;
                    pkt_id(k) = pkt_id_tmp(2);

                    pkt_type(k) = tx_packet_list(k).header.pkt_type;
                end
    
                time = cellstr(num2str(time(:), '%.2f'));
                src = cellstr(num2str(src(:), '%d'));
                dest = cellstr(num2str(dest(:), '%d'));
                pkt_id = cellstr(num2str(pkt_id(:), '%d'));
                pkt_type = cellstr(num2str(pkt_type(:), '%d'));

                app.TxTable.Data = [time src dest pkt_id pkt_type];
            end

        end
    end

    %% RX TABLE
    methods (Access = public)

        function updateRxTable(app)

            node = app.selectedNode;

            rx_packet_list = app.mainApp.NETWORK.node_list(node).rx_packet;
            
            app.RxTable.Data = [];

            if ~isempty(rx_packet_list)

                time = zeros(length(rx_packet_list), 1);
                src = zeros(length(rx_packet_list), 1);
                dest = zeros(length(rx_packet_list), 1);
                pkt_id = zeros(length(rx_packet_list), 1);
                pkt_type = zeros(length(rx_packet_list), 1);

                for k = 1:length(rx_packet_list)
                    time(k) = rx_packet_list(k).timestamp;
                    src(k) = rx_packet_list(k).header.src;
                    dest(k) = rx_packet_list(k).header.dest;

                    pkt_id_tmp = rx_packet_list(k).header.pkt_id;
                    pkt_id(k) = pkt_id_tmp(2);

                    pkt_type(k) = rx_packet_list(k).header.pkt_type;
                end

                time = cellstr(num2str(time(:), '%.2f'));
                src = cellstr(num2str(src(:), '%d'));
                dest = cellstr(num2str(dest(:), '%d'));
                pkt_id = cellstr(num2str(pkt_id(:), '%d'));
                pkt_type = cellstr(num2str(pkt_type(:), '%d'));

                app.RxTable.Data = [time src dest pkt_id pkt_type];
            end

        end

    end

    %% CLOSE APP
    methods (Access = public)

        function closeAppWindow(app)
            app.timerStop();
            delete(app);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, selectedNode)
            app.UIFigure.Visible = 'off';

            % Get information about main app
            app.mainApp = mainApp;
            
            % Get selected node
            app.selectedNode = selectedNode;

            % Change Lable
            app.NodeTrafficLabel.Text = strcat("Node ", string(selectedNode), " Traffic");
            % Initialize Tx Table
            app.updateTxTable();
            
            % Initialize Rx Table
            app.updateRxTable();

            % Initialize Timer
            app.timerInit();
            app.timerStart();

            % Reposition App            
            app.repositionApp();
            
            app.UIFigure.Visible = 'on';

        end

        % Callback function: not associated with a component
        function UpdateButtonPushed(app, event)
            % Update Tx Table
            app.updateTxTable();

            % Update Rx Table
            app.updateRxTable();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                app.closeAppWindow();
            catch
                delete(app);
            end
        end

        % Callback function: not associated with a component
        function UpdateButtonValueChanged(app, event)
            app.getNodeFromDropDown();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TXPacketsPanel = uipanel(app.UIFigure);
            app.TXPacketsPanel.Title = 'TX Packets';
            app.TxTable = uitable(app.TXPacketsPanel);
            app.TxTable.ColumnName = {'time'; 'src'; 'dest'; 'pkt_id'; 'pkt_type'};
            app.TxTable.RowName = {};
            app.RXPacketsPanel = uipanel(app.UIFigure);
            app.RXPacketsPanel.Title = 'RX Packets';
            app.RxTable = uitable(app.RXPacketsPanel);
            app.RxTable.ColumnName = {'time'; 'src'; 'dest'; 'pkt_id'; 'pkt_type'};
            app.RxTable.RowName = {};
            app.NodeTrafficLabel = uilabel(app.UIFigure);
            app.NodeTrafficLabel.Text = 'Node Traffic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pkt_table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end